classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        R_GPS_FaktorMeterLabel        matlab.ui.control.Label
        Sld_R_GPS                     matlab.ui.control.Slider
        SzeneListBox                  matlab.ui.control.ListBox
        Button                        matlab.ui.control.StateButton
        GeradeLabel                   matlab.ui.control.Label
        SinusabfahrenLabel            matlab.ui.control.Label
        KreisfahrtLabel               matlab.ui.control.Label
        Noise_WinkelMesswerteGradeLabel  matlab.ui.control.Label
        Noise_Messwert_Winkel         matlab.ui.control.Slider
        UIAxes_Roboterposition        matlab.ui.control.UIAxes
        Btn_Messdaten                 matlab.ui.control.Button
        Btn_Geschwindigkeit           matlab.ui.control.Button
        Btn_Robotoerposition          matlab.ui.control.Button
        Btn_Winkelgeschwindigkeit     matlab.ui.control.Button
        EinstellungenLabel            matlab.ui.control.Label
        ErgebnisLabel                 matlab.ui.control.Label
        SzeneLabel                    matlab.ui.control.Label
        SensorenLabel                 matlab.ui.control.Label
        CbxWinkelsensoren             matlab.ui.control.CheckBox
        CbxGPS                        matlab.ui.control.CheckBox
        GeschwindigkeitCheckBox       matlab.ui.control.CheckBox
        Noise_X_YMesswerteMeterLabel  matlab.ui.control.Label
        Sld_Noise_X_Y_Messwerte       matlab.ui.control.Slider
        R_Winkelsensor_FaktorGradeLabel  matlab.ui.control.Label
        Sld_R_Winkelsensor_Faktor     matlab.ui.control.Slider
        XmSpinnerLabel                matlab.ui.control.Label
        XmSpinner                     matlab.ui.control.Spinner
        QMatrixLabel                  matlab.ui.control.Label
        DiagonaleEintrgederQMatrixLabel  matlab.ui.control.Label
        YmSpinnerLabel                matlab.ui.control.Label
        YmSpinner                     matlab.ui.control.Spinner
        x_pmsSpinnerLabel             matlab.ui.control.Label
        x_pmsSpinner                  matlab.ui.control.Spinner
        y_pmsSpinnerLabel             matlab.ui.control.Label
        y_pmsSpinner                  matlab.ui.control.Spinner
        x_p_RmsSpinnerLabel           matlab.ui.control.Label
        x_p_RmsSpinner                matlab.ui.control.Spinner
        alphaGradSpinnerLabel         matlab.ui.control.Label
        alphaGradSpinner              matlab.ui.control.Spinner
        alpha_pGradSpinnerLabel       matlab.ui.control.Label
        alpha_pGradSpinner            matlab.ui.control.Spinner
        BtnRoboterposition_simple_2   matlab.ui.control.Button
        SteigungSpinnerLabel          matlab.ui.control.Label
        SteigungSpinner               matlab.ui.control.Spinner
        WinkelsensorLabel             matlab.ui.control.Label
        GPSLabel                      matlab.ui.control.Label
    end


    properties (Access = private)
        Property3 % Description
        
        %Sensoren
        Winkelsensoren=1;
        GPS=1;
        Geschwindigkeit=0;
        R_GPS_Faktor=1;
        Noise_X_Y_Messwerte=0;
        R_Winkelsensor_Faktor=1;
        w_noise=0;
        
        %Variabellen für Graphen
        szene='1';
        Gewicht_Q=1;
        it;
        gamma;
        Messung;
        out;
        out_nach_pred;
        varianz_y_nachp;
        varianz_x_nachp;
        varianz_y;
        varianz_x;
        x_berechnet;
        y_berechnet;
        s_d;
        x;
        y;
        Q_Matrix_x;
        Q_Matrix_y;
        Q_Matrix_x_p;
        Q_Matrix_y_p;
        Q_Matrix_x_r_p;
        Q_Matrix_alpha;
        Q_Matrix_alpha_p;
        Steigung_Gerade;
        Messung_GPS;
    end


    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)

        close all;
        %%Initalisierung der Q-Matrix;
        app.Q_Matrix_x=app.XmSpinner.Value;
        app.Q_Matrix_y=app.YmSpinner.Value;
        app.Q_Matrix_x_p=app.x_pmsSpinner.Value;
        app.Q_Matrix_y_p=app.y_pmsSpinner.Value;
        app.Q_Matrix_x_r_p=app.x_p_RmsSpinner.Value;
        app.Q_Matrix_alpha=app.alphaGradSpinner.Value;
        app.Q_Matrix_alpha_p=app.alpha_pGradSpinner.Value;        
        
        cla(app.UIAxes_Roboterposition);


        s.dt =1;%Zeitschritt
        app.w_noise=app.Noise_Messwert_Winkel.Value;
        noise_gain=app.w_noise*(pi/180);

        if (app.szene=='1')
            app.x=-1:1:60;
            m=0;b=15;
            app.Steigung_Gerade=app.SteigungSpinner.Value;
            app.y=app.Steigung_Gerade*app.x+b;
        elseif (app.szene=='2')
            app.x=-100:1:100;
            m=15;b=50;
            app.y=m*sin(app.x*pi/32)+b;
        elseif (app.szene=='3')  
            t=0:2*pi/60:2*pi;
            r=20;
            x_0=0;
            y_0=50;
            app.y=r*sin(t)+y_0;
            app.x=r*cos(t)+x_0;
        end
        
        app.it=length(app.x); %Anzahl der Messungen
        s.d=120;%Kameradistanz
        
        beta  = atan2(app.y,(s.d-app.x));
        app.gamma = atan2(app.y,(s.d+app.x));
        app.Messung = [beta+noise_gain*randn(1,app.it);app.gamma+noise_gain*randn(1,app.it)];
        

        %% Initiale Startposition:
        %     [      x;     y; dx; dy; dxR;alpha ;dalpha];
        s.x = [ app.x(1);app.y(1);  0;  0;   0;     0;     0];
        s.P = 10*eye(7); % Initiale Abweichung der Startposition
        
        % Process Noise Covariance für die Prädiktion (Konstante)
        % s.Q=0.01*eye(7);%Achtung!--> schwachsinnig für alle Statevektor-Daten die
        % gleiche Varianz anzunehmen
        %Besser:

        s.Q=app.Gewicht_Q*[ app.Q_Matrix_x,0,0,0,0,0,0;...
               0, app.Q_Matrix_y,0,0,0,0,0;...
               0,0,app.Q_Matrix_x_p,0,0,0,0;...
               0,0,0, app.Q_Matrix_y_p,0,0,0;...
               0,0,0,0,app.Q_Matrix_x_r_p,0,0;...
               0,0,0,0,0, app.Q_Matrix_alpha*(pi/180),0;...
               0,0,0,0,0,0,app.Q_Matrix_alpha_p*(pi/180)];
        %% Messungen durchgehen
        out=[];
        out_nach_pred=[];
        varianz_x=[];
        varianz_y=[];
        varianz_x_nachp=[];
        varianz_y_nachp=[];
        for k=1:app.it-1
            
            
         %% Zeitupdate, predict (Position schätzen)
        if k==1
        else
            s=predict(s);
        end   
            
        
        %% Zeitupdate, predict (Position schätzen)
        s=predict(s);
        
        out_nach_pred=horzcat(out_nach_pred,s.x);
        
        %% Messupdate (korregieren)
        %Messung aufrufen:
        %Verrauscht X,Y Messwerte
        
        if (app.Winkelsensoren==1) 
            Z=app.Messung(:,k+1);
            app.R_Winkelsensor_Faktor=app.Sld_R_Winkelsensor_Faktor;
            R_Matrix_Winkelsensor=app.R_Winkelsensor_Faktor.Value*(pi/180)*eye(2);
            s=messupdate(s,Z,R_Matrix_Winkelsensor);
            %Verrauschte Messdaten auf XY-zurückrechnen (nur zur Darstellung)
            
            x_berechnet(k)=s.d*(tan(Z(1))-tan(Z(2)))/(tan(Z(1))+tan(Z(2)));
            y_berechnet(k)=2*s.d*tan(Z(1))*tan(Z(2))/(tan(Z(1))+tan(Z(2)));
            %Fuer Darstellung
            app.x_berechnet=x_berechnet;
            app.y_berechnet=y_berechnet;
        end
        
        app.Messung_GPS=[app.x+app.Noise_X_Y_Messwerte*randn(1,app.it);app.y+app.Noise_X_Y_Messwerte*randn(1,app.it)];
        if (app.GPS==1) 
           
            %Zum Plotten
            Z_GPS=app.Messung_GPS(:,k+1);
             
            R_GPS=app.R_GPS_Faktor*eye(2);
            s=messupdate_GPS(s,Z_GPS,R_GPS);
             

        end   
        varianz_x_nachp=horzcat(varianz_x_nachp,s.P(1,1));%Varianz für jeden Zeitschritt mitschreiben
        varianz_y_nachp=horzcat(varianz_y_nachp,s.P(2,2));
        out=horzcat(out,s.x);%Kompletten Statevektor für jedenzeitschritt k in out schreiben
        varianz_x=horzcat(varianz_x,s.P(1,1));%Varianz für jeden Zeitschritt mitschreiben
        varianz_y=horzcat(varianz_y,s.P(2,2));

        end
        
        %% Plot der Daten

        %% X-Y-Positionen
        plot(app.UIAxes_Roboterposition,-s.d,0,'LineStyle','none','Marker','O','MarkerSize',10,'MarkerFaceColor',[0.85 0.33 0.1],'MarkerEdgeColor','k','LineWidth',1);
        hold(app.UIAxes_Roboterposition,'on')
        plot(app.UIAxes_Roboterposition,s.d,0,'LineStyle','none','Marker','O','MarkerSize',10,'MarkerFaceColor',[0 0.400000005960464 0],'MarkerEdgeColor','k','LineWidth',1)
        plot(app.UIAxes_Roboterposition,app.x,app.y,'LineWidth',1.5,'color',[0 0.447058826684952 0.74117648601532])
        plot(app.UIAxes_Roboterposition,out_nach_pred(1,:),out_nach_pred(2,:),'--','LineWidth',1.5,'color',[0.635294139385223 0.0784313753247261 0.184313729405403])
        plot(app.UIAxes_Roboterposition,out(1,:),out(2,:),'LineWidth',1.5,'color',[0.929411768913269 0.694117665290833 0.125490203499794])
      
        plot(app.UIAxes_Roboterposition,app.x_berechnet,app.y_berechnet,'LineStyle','none','Marker','*','color',[0.466666668653488 0.674509823322296 0.18823529779911])   
        
        if (app.GPS==1) 
            plot(app.UIAxes_Roboterposition,app.Messung_GPS(1,:),app.Messung_GPS(2,:),'LineStyle','none','Marker','*','color',[0.6 0.0 0.2])
        end 
        hold(app.UIAxes_Roboterposition,'off')

        app.out=out;
        app.out_nach_pred=out_nach_pred;
        app.varianz_y_nachp=varianz_y_nachp;
        app.varianz_x_nachp=varianz_x_nachp;
        app.varianz_y=varianz_y;
        app.varianz_x=varianz_x;

        app.s_d=s.d;

        end

        % Value changed function: SzeneListBox
        function SzeneListBoxValueChanged(app, event)
            app.szene = app.SzeneListBox.Value;
            ButtonValueChanged(app, event)
        end

        % Button pushed function: Btn_Messdaten
        function Btn_MessdatenPushed(app, event)
            figure('Name','Generierte Messdaten');
            plot(1:app.it,app.gamma);plot(1:app.it,app.Messung);legend('beta','gamma','beta-Messung','gamma-Messung');
        end

        % Callback function
        function Btn_VarianzButtonPushed(app, event)
        figure('Name','Varianz der Position')
        plot(app.varianz_x,'LineWidth',1.5);hold on;xlabel('Diskrete Zeitschritte');ylabel('Varianz in m');set(gca,'FontSize',20)
        plot(app.varianz_y,'LineWidth',1.5)
        
        plot(app.varianz_x_nachp,'LineWidth',1.5)
        plot(app.varianz_y_nachp,'LineWidth',1.5)
        
        %Differenz zur Wahrenposition nach Messupdate:
        plot(app.out(1,:)-app.x(1:end-1),'LineWidth',1.5)
        plot(app.out(2,:)-app.y(1:end-1),'LineWidth',1.5)
        %Differenz zur Wahrenposition nach Prädiktion:
        plot(app.out_nach_pred(1,:)-app.x(1:end-1),'LineWidth',1.5)
        plot(app.out_nach_pred(2,:)-app.y(1:end-1),'LineWidth',1.5)
        
        legend('Varianz in x','Varianz in y','Varianz in x nach präd','Varianz in y nach präd','Differenz nach messupdate von X','Differenz nach messupdate von Y','Differenz nach prädiktion von X','Differenz nach prädiktion von Y');
        end

        % Value changed function: CbxWinkelsensoren
        function CbxWinkelsensorenValueChanged(app, event)
            app.Winkelsensoren = app.CbxWinkelsensoren.Value;
            ButtonValueChanged(app, event)
        end

        % Value changed function: CbxGPS
        function CbxGPSValueChanged(app, event)
            app.GPS = app.CbxGPS.Value;
            ButtonValueChanged(app, event)
        end

        % Button pushed function: Btn_Robotoerposition
        function Btn_RobotoerpositionButtonPushed(app, event)
        figure('Name','Roboterposition in Weltkoordinaten')
        labels = cellstr( num2str([1:app.it-1]') );  %' # labels correspond to their order
        
        hold on
        plot(-app.s_d,0,'LineStyle','none','Marker','O','MarkerSize',10,'MarkerFaceColor',[0.85 0.33 0.1],'MarkerEdgeColor','k','LineWidth',1)

        plot(app.s_d,0,'LineStyle','none','Marker','O','MarkerSize',10,'MarkerFaceColor',[0 0.400000005960464 0],'MarkerEdgeColor','k','LineWidth',1)
        plot(app.x,app.y,'LineWidth',1.5,'color',[0 0.447058826684952 0.74117648601532])

        
        plot(app.out(1,:),app.out(2,:),'Marker','*','LineWidth',1.5,'color',[0.929411768913269 0.694117665290833 0.125490203499794])
        text(app.out(1,:),app.out(2,:), labels, 'VerticalAlignment','bottom','HorizontalAlignment','right')
        
        if (app.Winkelsensoren==1) 
            plot(app.x_berechnet,app.y_berechnet,'LineStyle','none','Marker','*','color',[0.466666668653488 0.674509823322296 0.18823529779911])
            text(app.x_berechnet,app.y_berechnet, labels, 'VerticalAlignment','bottom','HorizontalAlignment','right')
        end
        
        if (app.GPS==1) 
            plot(app.Messung_GPS(1,:),app.Messung_GPS(2,:),'LineStyle','none','Marker','*','color',[0.6 0.0 0.2])
            %text(app.Messung_GPS(1,:),app.Messung_GPS(2,:), labels, 'VerticalAlignment','bottom','HorizontalAlignment','right')
        end 
                 
        for k=1:app.it-2
        
        %Prädiktion
        % von Messupdate auf Prädiktion
        plot_arrow( app.out(1,k),app.out(2,k),app.out_nach_pred(1,k+1),app.out_nach_pred(2,k+1),'linewidth',1,'color',[0.5 0.5 0.5],'facecolor',[0.5 0.5 0.5],...
            'headwidth',0.025,'headheight',0.05);
        %Messupdate
        % von prädiktion auf korrigierenden Messwert
        plot_arrow(app.out_nach_pred(1,k),app.out_nach_pred(2,k),app.out(1,k),app.out(2,k),'linewidth',1,'color',[0.6 0.2 0],'facecolor',[0.6 0.2 0],...
            'headwidth',0.025,'headheight',0.05);    
        
        end
        
        legend('Winkelsensor beta','Winkelsensor gamma','ist Position','Messupdate','Messdaten beta-gamma in x-y','Messdaten_GPS','Prädiktion','','Messkorrektur');set(gca,'FontSize',20)
        axis([-app.s_d app.s_d 0  max(app.y)])
        xlabel('Position X');ylabel('Position Y');
        end

        % Value changed function: Sld_R_GPS
        function Sld_R_GPSValueChanged(app, event)
            app.R_GPS_Faktor = app.Sld_R_GPS.Value;
            ButtonValueChanged(app, event)
        end

        % Value changed function: Sld_Noise_X_Y_Messwerte
        function Sld_Noise_X_Y_MesswerteValueChanged(app, event)
            app.Noise_X_Y_Messwerte = app.Sld_Noise_X_Y_Messwerte.Value;
            ButtonValueChanged(app, event)
        end

        % Callback function
        function Sld_Gewicht_QValueChanged(app, event)
            app.Gewicht_Q = app.Sld_Gewicht_Q.Value;
            ButtonValueChanged(app, event)
        end

        % Button pushed function: Btn_Geschwindigkeit
        function Btn_GeschwindigkeitButtonPushed(app, event)
            figure('Name','Geschwindigkeit')
            plot(app.out(3,:)); hold on;plot(app.out(6,:));plot(app.out(5,:));hold off;xlabel('Diskrete Zeitschritte');ylabel('Geschwindigkeit in m/sec');legend('Geschwindigkeit in X','Geschwindigkeit in Y','Geschwindigkeit in Roboterrichtung');set(gca,'FontSize',20);
            
        end

        % Button pushed function: BtnRoboterposition_simple_2
        function BtnRoboterposition_simpleButtonPushed(app, event)
            figure('Name','Roboterposition in Weltkoordinaten')
            labels = cellstr( num2str([1:app.it-1]') );  %' # labels correspond to their order
            
            
            plot(-app.s_d,0,'LineStyle','none','Marker','O','MarkerSize',10,'MarkerFaceColor',[0.85 0.33 0.1],'MarkerEdgeColor','k','LineWidth',1)
            hold on
            plot(app.s_d,0,'LineStyle','none','Marker','O','MarkerSize',10,'MarkerFaceColor',[0 0.400000005960464 0],'MarkerEdgeColor','k','LineWidth',1)
            plot(app.x,app.y,'LineWidth',1.5,'color',[0 0.447058826684952 0.74117648601532])
    
            
            plot(app.out(1,:),app.out(2,:),'Marker','*','LineWidth',1.5,'color',[0.929411768913269 0.694117665290833 0.125490203499794])
            text(app.out(1,:),app.out(2,:), labels, 'VerticalAlignment','bottom','HorizontalAlignment','right')
            
            if (app.Winkelsensoren==1) 
                plot(app.x_berechnet,app.y_berechnet,'LineStyle','none','Marker','*','color',[0.466666668653488 0.674509823322296 0.18823529779911])
                text(app.x_berechnet,app.y_berechnet, labels, 'VerticalAlignment','bottom','HorizontalAlignment','right')
            end
            
            if (app.GPS==1) 
                plot(app.Messung_GPS(1,:),app.Messung_GPS(2,:),'LineStyle','none','Marker','*','color',[0.6 0.0 0.2])
                text(app.Messung_GPS(1,:),app.Messung_GPS(2,:), labels, 'VerticalAlignment','bottom','HorizontalAlignment','right')
            end 
        
            
        end

        % Value changed function: XmSpinner
        function XmSpinnerValueChanged(app, event)
            app.Q_Matrix_x = app.XmSpinner.Value;
             
        end

        % Value changed function: Noise_Messwert_Winkel
        function Noise_Messwert_WinkelValueChanged(app, event)
            value = app.Noise_Messwert_Winkel.Value;
            ButtonValueChanged(app, event)
        end

        % Value changed function: Sld_R_Winkelsensor_Faktor
        function Sld_R_Winkelsensor_FaktorValueChanged(app, event)
            app.R_Winkelsensor_Faktor= app.Sld_R_Winkelsensor_Faktor.Value;
            ButtonValueChanged(app, event)
        end

        % Value changed function: SteigungSpinner
        function SteigungSpinnerValueChanged(app, event)
            app.Steigung_Gerade = app.SteigungSpinner.Value;
            ButtonValueChanged(app, event)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.R_GPS_FaktorMeterLabel = uilabel(app.UIFigure);
            app.R_GPS_FaktorMeterLabel.Text = {'R_GPS_Faktor'; '[Meter]'};
            app.Sld_R_GPS = uislider(app.UIFigure);
            app.Sld_R_GPS.Limits = [0 25];
            app.Sld_R_GPS.ValueChangedFcn = createCallbackFcn(app, @Sld_R_GPSValueChanged, true);
            app.Sld_R_GPS.Value = 6;
            app.SzeneListBox = uilistbox(app.UIFigure);
            app.SzeneListBox.Items = {'1', '2', '3', ''};
            app.SzeneListBox.ValueChangedFcn = createCallbackFcn(app, @SzeneListBoxValueChanged, true);
            app.SzeneListBox.Value = '1';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Start';
            app.GeradeLabel = uilabel(app.UIFigure);
            app.GeradeLabel.Text = 'Gerade: ';
            app.SinusabfahrenLabel = uilabel(app.UIFigure);
            app.SinusabfahrenLabel.Text = 'Sinus abfahren';
            app.KreisfahrtLabel = uilabel(app.UIFigure);
            app.KreisfahrtLabel.Text = 'Kreisfahrt';
            app.Noise_WinkelMesswerteGradeLabel = uilabel(app.UIFigure);
            app.Noise_WinkelMesswerteGradeLabel.Text = {'Noise_Winkel'; 'Messwerte [Grade]'};
            app.Noise_Messwert_Winkel = uislider(app.UIFigure);
            app.Noise_Messwert_Winkel.Limits = [0 1];
            app.Noise_Messwert_Winkel.ValueChangedFcn = createCallbackFcn(app, @Noise_Messwert_WinkelValueChanged, true);
            app.Noise_Messwert_Winkel.Value = 0.5;
            app.UIAxes_Roboterposition = uiaxes(app.UIFigure);
            title(app.UIAxes_Roboterposition, 'Roboterposition in Weltkoordinaten')
            xlabel(app.UIAxes_Roboterposition, 'X [m]')
            ylabel(app.UIAxes_Roboterposition, 'Y [m]')
            app.Btn_Messdaten = uibutton(app.UIFigure, 'push');
            app.Btn_Messdaten.ButtonPushedFcn = createCallbackFcn(app, @Btn_MessdatenPushed, true);
            app.Btn_Messdaten.Text = 'Messdaten';
            app.Btn_Geschwindigkeit = uibutton(app.UIFigure, 'push');
            app.Btn_Geschwindigkeit.ButtonPushedFcn = createCallbackFcn(app, @Btn_GeschwindigkeitButtonPushed, true);
            app.Btn_Geschwindigkeit.Text = 'Geschwindigkeit';
            app.Btn_Robotoerposition = uibutton(app.UIFigure, 'push');
            app.Btn_Robotoerposition.ButtonPushedFcn = createCallbackFcn(app, @Btn_RobotoerpositionButtonPushed, true);
            app.Btn_Robotoerposition.Text = 'Position_Komplex';
            app.Btn_Winkelgeschwindigkeit = uibutton(app.UIFigure, 'push');
            app.Btn_Winkelgeschwindigkeit.Text = 'Winkelgeschwindigkeit';
            app.EinstellungenLabel = uilabel(app.UIFigure);
            app.EinstellungenLabel.Text = 'Einstellungen';
            app.ErgebnisLabel = uilabel(app.UIFigure);
            app.ErgebnisLabel.Text = 'Ergebnis';
            app.SzeneLabel = uilabel(app.UIFigure);
            app.SzeneLabel.Text = 'Szene';
            app.SensorenLabel = uilabel(app.UIFigure);
            app.SensorenLabel.Text = 'Sensoren';
            app.CbxWinkelsensoren = uicheckbox(app.UIFigure);
            app.CbxWinkelsensoren.ValueChangedFcn = createCallbackFcn(app, @CbxWinkelsensorenValueChanged, true);
            app.CbxWinkelsensoren.Text = 'Winkelsensoren';
            app.CbxWinkelsensoren.Value = true;
            app.CbxGPS = uicheckbox(app.UIFigure);
            app.CbxGPS.ValueChangedFcn = createCallbackFcn(app, @CbxGPSValueChanged, true);
            app.CbxGPS.Text = 'GPS';
            app.CbxGPS.Value = true;
            app.GeschwindigkeitCheckBox = uicheckbox(app.UIFigure);
            app.GeschwindigkeitCheckBox.Text = 'Geschwindigkeit';
            app.Noise_X_YMesswerteMeterLabel = uilabel(app.UIFigure);
            app.Noise_X_YMesswerteMeterLabel.Text = {'Noise_X_Y'; 'Messwerte [Meter]'};
            app.Sld_Noise_X_Y_Messwerte = uislider(app.UIFigure);
            app.Sld_Noise_X_Y_Messwerte.Limits = [0 10];
            app.Sld_Noise_X_Y_Messwerte.ValueChangedFcn = createCallbackFcn(app, @Sld_Noise_X_Y_MesswerteValueChanged, true);
            app.R_Winkelsensor_FaktorGradeLabel = uilabel(app.UIFigure);
            app.R_Winkelsensor_FaktorGradeLabel.Text = {'R_Winkelsensor'; '_Faktor'; '[Grade]'};
            app.Sld_R_Winkelsensor_Faktor = uislider(app.UIFigure);
            app.Sld_R_Winkelsensor_Faktor.Limits = [0 1];
            app.Sld_R_Winkelsensor_Faktor.ValueChangedFcn = createCallbackFcn(app, @Sld_R_Winkelsensor_FaktorValueChanged, true);
            app.Sld_R_Winkelsensor_Faktor.Value = 0.5;
            app.XmSpinnerLabel = uilabel(app.UIFigure);
            app.XmSpinnerLabel.Text = 'X [m]';
            app.XmSpinner = uispinner(app.UIFigure);
            app.XmSpinner.Limits = [0 Inf];
            app.XmSpinner.ValueChangedFcn = createCallbackFcn(app, @XmSpinnerValueChanged, true);
            app.XmSpinner.Value = 1;
            app.QMatrixLabel = uilabel(app.UIFigure);
            app.QMatrixLabel.Text = 'Q-Matrix';
            app.DiagonaleEintrgederQMatrixLabel = uilabel(app.UIFigure);
            app.DiagonaleEintrgederQMatrixLabel.Text = 'Diagonale Einträge der Q-Matrix';
            app.YmSpinnerLabel = uilabel(app.UIFigure);
            app.YmSpinnerLabel.Text = 'Y [m]';
            app.YmSpinner = uispinner(app.UIFigure);
            app.YmSpinner.Limits = [0 Inf];
            app.YmSpinner.Value = 1;
            app.x_pmsSpinnerLabel = uilabel(app.UIFigure);
            app.x_pmsSpinnerLabel.Text = 'x_p [m/s]';
            app.x_pmsSpinner = uispinner(app.UIFigure);
            app.x_pmsSpinner.Limits = [0 Inf];
            app.x_pmsSpinner.Value = 2;
            app.y_pmsSpinnerLabel = uilabel(app.UIFigure);
            app.y_pmsSpinnerLabel.Text = 'y_p [m/s]';
            app.y_pmsSpinner = uispinner(app.UIFigure);
            app.y_pmsSpinner.Limits = [0 Inf];
            app.y_pmsSpinner.Value = 2;
            app.x_p_RmsSpinnerLabel = uilabel(app.UIFigure);
            app.x_p_RmsSpinnerLabel.Text = 'x_p_R [m/s²]';
            app.x_p_RmsSpinner = uispinner(app.UIFigure);
            app.x_p_RmsSpinner.Limits = [0 Inf];
            app.x_p_RmsSpinner.Value = 2;
            app.alphaGradSpinnerLabel = uilabel(app.UIFigure);
            app.alphaGradSpinnerLabel.Text = 'alpha [Grad]';
            app.alphaGradSpinner = uispinner(app.UIFigure);
            app.alphaGradSpinner.Limits = [0 Inf];
            app.alphaGradSpinner.Value = 3;
            app.alpha_pGradSpinnerLabel = uilabel(app.UIFigure);
            app.alpha_pGradSpinnerLabel.Text = 'alpha_p [Grad]';
            app.alpha_pGradSpinner = uispinner(app.UIFigure);
            app.alpha_pGradSpinner.Limits = [0 Inf];
            app.alpha_pGradSpinner.Value = 4;
            app.BtnRoboterposition_simple_2 = uibutton(app.UIFigure, 'push');
            app.BtnRoboterposition_simple_2.ButtonPushedFcn = createCallbackFcn(app, @BtnRoboterposition_simpleButtonPushed, true);
            app.BtnRoboterposition_simple_2.Text = 'Position_Simple';
            app.SteigungSpinnerLabel = uilabel(app.UIFigure);
            app.SteigungSpinnerLabel.Text = 'Steigung';
            app.SteigungSpinner = uispinner(app.UIFigure);
            app.SteigungSpinner.ValueChangedFcn = createCallbackFcn(app, @SteigungSpinnerValueChanged, true);
            app.WinkelsensorLabel = uilabel(app.UIFigure);
            app.WinkelsensorLabel.Text = 'Winkelsensor';
            app.GPSLabel = uilabel(app.UIFigure);
            app.GPSLabel.Text = 'GPS';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end